classdef shulte_table_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        StatusLabel               matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        Table                     matlab.ui.container.Tab
        GridLayoutTablePage       matlab.ui.container.GridLayout
        GridLayoutTableElement    matlab.ui.container.GridLayout
        UITable                   matlab.ui.control.Table
        Lamp                      matlab.ui.control.Lamp
        GridLayoutTimeAndButtons  matlab.ui.container.GridLayout
        ResetButton               matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        ResultArea                matlab.ui.control.TextArea
        TimeLabel                 matlab.ui.control.Label
        PlotTab                   matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        UIAxes                    matlab.ui.control.UIAxes
        OptionsTab                matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        ComplexitySpinner         matlab.ui.control.Spinner
        ComplexitySpinnerLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        options (1,1) model.Options
        shulteTable model.ShulteTable
        trainingTimer timer
        elapsedTimer controller.ElapsedTimer
        nextNumber uint32
        typoSoundEffect double
        typoSoundEffectSampleRate (1,1) double

        beginTime (1,1) datetime
        taskDurations (2, :) double
        mistakes (1,1) uint32
    end
    
    methods (Access = private)
        
        function initTable(app, repeatNumber)
            if nargin == 2
                app.shulteTable = model.ShulteTable(app.options.complexity, repeatNumber);
                app.TimeLabel.Text = "-- : --";
                app.beginTime = datetime('now');
            else
                app.shulteTable = model.ShulteTable(app.options.complexity);
            end
            numbers = app.shulteTable.numbers;

            data = arrayfun(@(x) ...
                sprintf('%s%02d', repmat(char(0160), 1, 2), x), ...
                numbers, 'UniformOutput', false);

            app.UITable.Data = data;
            app.UITable.ColumnWidth = num2cell(ones(1, app.options.complexity) * 40);

            app.UITable.Selection = [];
        end

        function app = switchLamp(app, state)
            switch (state)
                case "on"
                    app.Lamp.Color = controller.hex2rgb("#1d8e49");
                case "off"
                    app.Lamp.Color = controller.hex2rgb("#808080");
            end
        end

        function app = stopTrainingTimer(app)
            if ~isempty(app.trainingTimer) && isa(app.trainingTimer, 'timer') && isvalid(app.trainingTimer)
                stop(app.trainingTimer);
                delete(app.trainingTimer);
            end
        end

        function app = startTrainingTimer(app)
            app.stopTrainingTimer();

            app.trainingTimer = timer;
            app.trainingTimer.ExecutionMode = 'fixedSpacing';
            app.trainingTimer.Period = 1;
            app.trainingTimer.TimerFcn = @(src, event) app.updateTime();

            app.elapsedTimer = controller.ElapsedTimer();
            app.elapsedTimer.start();
            app.trainingTimer.start();
        end

        function app = updateTime(app)
            elapsed = app.elapsedTimer.getElapsed();
            [minutes, seconds] = controller.toMinutesAndSecconds(elapsed);
            app.TimeLabel.Text = sprintf("%02d : %02d", minutes, seconds);
        end

        function app = loadAssets(app)
            soundFile = fullfile(model.Config.typoSoundEffect{:});
            if exist(soundFile, 'file')
                [app.typoSoundEffect, app.typoSoundEffectSampleRate] = audioread(soundFile);
            else
                uialert(app.UIFigure, ['File not found: ' soundFile], 'Missing File');
            end
        end

        function app = incorrectrWarn(app)
            sound(app.typoSoundEffect, app.typoSoundEffectSampleRate);
        end

        function app = onCompleteTask(app)
            app.elapsedTimer.pause();
            app.stopTrainingTimer();
            
            duration = app.elapsedTimer.getElapsed();
            elapsedTime = minutes(datetime('now') - app.beginTime);

            app.ResultArea.Value{end + 1} = sprintf('%03.1f', duration);
            app.switchLamp('on');
            app.taskDurations(:, end + 1) = [elapsedTime; duration];
            plot(app.UIAxes, app.taskDurations(1, :), app.taskDurations(2, :), 'k.-');
        end

        function app = setOptions(app, options)
            arguments
                app shulte_table_ui
                options model.Options
            end
            app.ComplexitySpinner.Value = double(options.complexity);
            app.options = options;
        end

        function app = storeOptions(app)
            app.options.storeOptions();
        end
        
        function app = selectPreviousCell(app)
            expectedNext = app.shulteTable.nextNumber;
            if expectedNext == 1
                app.UITable.Selection = [];
                return
            end

            expeded = expectedNext - 1;

            comparator = @(x) str2double(replace(x, char(160), '')) == expeded;
            data = app.UITable.Data;
            [col, row] = find(comparator(data));
            app.UITable.Selection = [col, row];
        end

        function storeToDatabase(app)
            if numel(app.taskDurations) == 0
                return
            end

            session = model.Session();
            session.beginAt = app.beginTime;
            session.finishAt = app.beginTime + app.taskDurations(1, end);
            session.mistakes = app.mistakes;
            session.complexity = app.shulteTable.size;
            session.taskDurations = app.taskDurations;

            database.storeSessionToDatabase(session);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            database.initDatabase();

            app.options = app.options.loadOptions();
            app.setOptions(app.options);
            app.loadAssets();
            app.initTable(0);
            app.switchLamp("off");
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.initTable();
            app.startTrainingTimer();
            app.switchLamp('off');
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            if app.shulteTable.isCompleted()
                return
            end

            selection = num2cell(app.UITable.Selection);
            element = app.UITable.Data{selection{:}};
            value = replace(element, char(160), '');
            actualNumber = str2double(value);

            if app.shulteTable.isExpecedCell(actualNumber)
                app.shulteTable.next();
            else
                app.incorrectrWarn();
                app.selectPreviousCell();
                app.mistakes = app.mistakes + 1;
            end

            if app.shulteTable.isCompleted()
                app.onCompleteTask();
            end
        end

        % Value changed function: ComplexitySpinner
        function ComplexitySpinnerValueChanged(app, event)
            value = app.ComplexitySpinner.Value;
            app.options.complexity = value;
            app.options.storeOptions();
            app.storeToDatabase();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.stopTrainingTimer();
            app.taskDurations = [];
            app.initTable(0);
            app.ResultArea.Value = '';
            app.mistakes = 0;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.storeToDatabase();
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.GridLayoutTablePage = uigridlayout(app.Table);
            app.GridLayoutTimeAndButtons = uigridlayout(app.GridLayoutTablePage);
            app.TimeLabel = uilabel(app.GridLayoutTimeAndButtons);
            app.TimeLabel.Text = 'Time';
            app.ResultArea = uitextarea(app.GridLayoutTimeAndButtons);
            app.NextButton = uibutton(app.GridLayoutTimeAndButtons, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ResetButton = uibutton(app.GridLayoutTimeAndButtons, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GridLayoutTableElement = uigridlayout(app.GridLayoutTablePage);
            app.Lamp = uilamp(app.GridLayoutTableElement);
            app.UITable = uitable(app.GridLayoutTableElement);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.GridLayout3 = uigridlayout(app.PlotTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Productivity')
            xlabel(app.UIAxes, 'Attempt')
            ylabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.GridLayout2 = uigridlayout(app.OptionsTab);
            app.ComplexitySpinnerLabel = uilabel(app.GridLayout2);
            app.ComplexitySpinnerLabel.Text = 'Complexity';
            app.ComplexitySpinner = uispinner(app.GridLayout2);
            app.ComplexitySpinner.Limits = [2 10];
            app.ComplexitySpinner.ValueChangedFcn = createCallbackFcn(app, @ComplexitySpinnerValueChanged, true);
            app.ComplexitySpinner.Value = 5;
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shulte_table_ui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end